classdef realApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        StatesTab                   matlab.ui.container.Tab
        GridLayout5                 matlab.ui.container.GridLayout
        StateLablel                 matlab.ui.control.EditField
        StateLabelEditField_2Label  matlab.ui.control.Label
        RemoveButton                matlab.ui.control.Button
        PropertyNo1Label            matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ProcessCheckBox             matlab.ui.control.CheckBox
        ProcessDropDown             matlab.ui.control.DropDown
        UnitLabel_2                 matlab.ui.control.Label
        AddButton                   matlab.ui.control.Button
        TableAStatesListLabel       matlab.ui.control.Label
        ProUnitProp2DropDown        matlab.ui.control.DropDown
        ProUnitProp1DropDown        matlab.ui.control.DropDown
        ProStateUITable             matlab.ui.control.Table
        ProProp2EditField           matlab.ui.control.NumericEditField
        ProProp1EditField           matlab.ui.control.NumericEditField
        ProPropertyNo2DropDown      matlab.ui.control.DropDown
        ProPropertyNo1DropDown      matlab.ui.control.DropDown
        ProcessTab                  matlab.ui.container.Tab
        GridLayout2_2               matlab.ui.container.GridLayout
        AllProcessesCheckBox        matlab.ui.control.CheckBox
        ProcessListBoxLabel         matlab.ui.control.Label
        ProcessListBox              matlab.ui.control.ListBox
        ProcessLabelEditField       matlab.ui.control.EditField
        ProcessLabelEditFieldLabel  matlab.ui.control.Label
        ProRemoveButton             matlab.ui.control.Button
        FinalStateDropDown          matlab.ui.control.DropDown
        FinalStateDropDownLabel     matlab.ui.control.Label
        StartingStateDropDown       matlab.ui.control.DropDown
        StartingStateDropDownLabel  matlab.ui.control.Label
        ProcessAddButton            matlab.ui.control.Button
        ProcessDropDownNew          matlab.ui.control.DropDown
        ProcessDropDown_2Label      matlab.ui.control.Label
        ProUITable                  matlab.ui.control.Table
        PlotsTab                    matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        UnitIsentropicDropDown      matlab.ui.control.DropDown
        UnitIsoVolDropDown          matlab.ui.control.DropDown
        UnitIsoThermDropDown        matlab.ui.control.DropDown
        UnitIsoBarDropDown          matlab.ui.control.DropDown
        UnitPlotYDropDownLabel      matlab.ui.control.Label
        UnitPlotXDropDownLabel      matlab.ui.control.Label
        UnitPlotYDropDown           matlab.ui.control.DropDown
        UnitPlotXDropDown           matlab.ui.control.DropDown
        IsentropicValue             matlab.ui.control.EditField
        IsentropicCheckBox          matlab.ui.control.CheckBox
        ConstantVolValue            matlab.ui.control.EditField
        IsoThermValue               matlab.ui.control.EditField
        ConstantVolCheckBox         matlab.ui.control.CheckBox
        IsoThermCheckBox            matlab.ui.control.CheckBox
        IsoBarCheckBox              matlab.ui.control.CheckBox
        IsoBarLineValue             matlab.ui.control.EditField
        IsoLinesCheckBox            matlab.ui.control.CheckBox
        PlotButton                  matlab.ui.control.Button
        PlotDropDown                matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        DPlotTab                    matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        Plot3D                      matlab.ui.control.UIAxes
        PropertyCalcoldTab          matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        UnitLabel                   matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        StatesPropertiesLabel       matlab.ui.control.Label
        UnitProp2DropDown           matlab.ui.control.DropDown
        UnitProp1DropDown           matlab.ui.control.DropDown
        UITable                     matlab.ui.control.Table
        Prop2EditField              matlab.ui.control.NumericEditField
        Prop1EditField              matlab.ui.control.NumericEditField
        PropertyNo2DropDown         matlab.ui.control.DropDown
        PropertyNo2DropDownLabel    matlab.ui.control.Label
        PropertyNo1DropDown         matlab.ui.control.DropDown
        PropertyNo1DropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
     
        T
        P
        v
        u
        h
        Table
        Critical
        StateCounter = 1;
        ProcessCounter = 1;
        TableData % Description
        StateInd
        ProInd
        DataPVT
        fontSize = 18;
        fontSizeFigure = 18;
        IsoValuesT;
        hf
        hFontSize
        defautUnits = struct('v','m^3/kg', 'P','bar', 'T','^oC', 'uh','kJ/kg', 's','kJ/kgK');
        %allUnits = struct('v',{'m3_kg','L_kg'}, 'P',{'kPa','bar','Pa','MPa','psi','psf'} ...
            %'T',{'C','K','R','F'}, 'uh',{'kJ_kg','J_kg','Btu_lb'}, 's',{'kJ_kg','J_kg','Btu_lb'});
        allUnits = struct();
        plotTypes = {'P - V', 'T - V', 'T - S'};
        scaleFactor = 1.5
        
        uiPlot
        
        proStates
        lineWidth = 2;
        axisWidth = 2;
        isoLineWidth = 1.5;
        processLineWidth = 1;

        %percentage that values must differ to not be recognized as an
        %iso-process
        processDetectTolerance = 0.01;

        %THIS NEEDS TO MATCH WITH THE PROCESS DETECT FUNCTION
        Processes = {'Isobaric','Isothermal','Constant Volume','Isentropic'};


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            [app.Table,app.Critical] = GenTableNew(); 
            app.DataPVT = PVTData();
      
            
app.hf = findobj(app.UIFigure, '-property', 'FontSize');
%app.hFontSize = cell2mat(get(app.hf,'FontSize'));
%position = app.UIFigure.Position;

%widthF = position(3);

%pressure: ['kPa','bar','Pa,'MPa','psi','psf']
%temperature: ['^oC','K','^oR','^oF']
%specific volume: ['m^3/kg','L/kg']
%energy: [J,kJ]
%specific energy: ['kJ/kg','J/kg','Btu/lb']
%specific entropy: ['kJ/kgK','J/kgK','Btu/lbR']


%set global font size
newFontSize = 18;%double(widthF) * app.hFontSize / 1463.0;
set(app.hf,{'FontSize'}, num2cell(newFontSize));
app.allUnits.v = {'m^3/kg','L/kg'};
app.allUnits.P = {'bar','kPa','Pa','MPa','psi','psf'};
app.allUnits.T = {'^oC','K','^oR','^oF'};
app.allUnits.uh = {'kJ/kg','J/kg','Btu/lb'};
app.allUnits.s = {'kJ/kgK','J/kgK','Btu/lbR'};


app.PlotDropDown.Items = app.plotTypes;
%plotTypes = {'P - V', 'T - V', 'T - S'};

%initialize these so that the defaults work and aren't empty
app.UnitPlotXDropDown.Items = app.allUnits.v;
app.UnitPlotYDropDown.Items = app.allUnits.P;

%position / size
posiSize = get(app.UIFigure,'Position');

newPosition = posiSize;
% newPosition(3) = posiSize(1) + app.scaleFactor*(posiSize(3) - posiSize(1));
% newPosition(4) = posiSize(2) + app.scaleFactor*(posiSize(4) - posiSize(2));

newPosition(3) = app.scaleFactor*posiSize(3);
newPosition(4) = app.scaleFactor*posiSize(4);


set(app.UIFigure,'Position',newPosition)

%cosmetic changes
%no matter what?
box(app.UIAxes,'on')
set(app.UIAxes,'linewidth',app.axisWidth,'FontSize',app.fontSizeFigure)
grid(app.UIAxes,'on')


%-2.0694 0.8472 12.6389 9.3056


% posiSize = get(app.UIFigure,'PaperPosition')
%
% newPosition = posiSize;
% newPosition(3) = posiSize(1) + app.scaleFactor*(posiSize(3) - posiSize(1))
% newPosition(4) = posiSize(2) + app.scaleFactor*(posiSize(4) - posiSize(2))
% 
% set(app.UIFigure,'PaperPosition',newPosition)


%get(app.UIFigure)
%get(app.UnitProp1DropDown)

%testing latex interpretation
%set(app.UnitProp1DropDown,'Interpreter','latex')


app.UnitIsoThermDropDown.Items = app.allUnits.T;
app.UnitIsoBarDropDown.Items = app.allUnits.P;
app.UnitIsoVolDropDown.Items = app.allUnits.v;
app.UnitIsentropicDropDown.Items = app.allUnits.s;


app.ProcessDropDownNew.Items = app.Processes;

        end

        % Value changed function: PropertyNo1DropDown
        function PropertyNo1DropDownValueChanged(app, event)

            % First Property DropDown
            
            
            app.PropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Internal Energy - u','Enthalpy - h','Entropy - s','x'};
            Prop1 = app.PropertyNo1DropDown.Value;
            app.UnitProp2DropDown.Items = {};

                switch Prop1  
                    case 'Select'
                        app.UnitProp1DropDown.Items = {};
                        fprintf("1")
                        
                    case 'Pressure' 
                        %app.UnitProp1DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
                        app.UnitProp1DropDown.Items = app.allUnits.P;
                        
                        app.PropertyNo2DropDown.Items(2) = [];
                        fprintf("2")
                    
                    case 'Temperature'  
                        %app.UnitProp1DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
                        app.UnitProp1DropDown.Items = app.allUnits.T;
                        
                        app.PropertyNo2DropDown.Items(3) = [];
                        fprintf("3")
                        
                    case 'Specific Volume - v'  
                        %app.UnitProp1DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};
                        app.UnitProp1DropDown.Items = app.allUnits.v;
                        
                        app.PropertyNo2DropDown.Items(4) = [];
                        fprintf("4")
                    
                    case 'Internal Energy - u'
                        %app.UnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.UnitProp1DropDown.Items = app.allUnits.uh;
                        
                        app.PropertyNo2DropDown.Items(5) = [];
                        fprintf("5")
                    
                    case 'Enthalpy - h'    
                        %app.UnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.UnitProp1DropDown.Items = app.allUnits.uh;
                        app.PropertyNo2DropDown.Items(6) = [];
                        fprintf("6")
                        
                    case 'Entropy - s'
                        %app.UnitProp1DropDown.Items = {'KJ/Kg * K'};
                        app.UnitProp1DropDown.Items = app.allUnits.s;
                        
                        app.PropertyNo2DropDown.Items(7) = [];
                        app.allUnits.s
                        
                    case 'x'
                        app.UnitProp1DropDown.Items = {'No Unit'};
                        app.PropertyNo2DropDown.Items(8) = [];
                        fprintf("8")
                end


        end

        % Value changed function: PropertyNo2DropDown
        function PropertyNo2DropDownValueChanged(app, event)

                  Prop2 = app.PropertyNo2DropDown.Value;

                switch Prop2  
                    case 'Select'
                        app.UnitProp2DropDown.Items = {};
                    
                    case 'Pressure' 
                        %app.UnitProp2DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
                        app.UnitProp2DropDown.Items = app.allUnits.P;
                        
                    case 'Temperature'  
                        %app.UnitProp2DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
                        app.UnitProp2DropDown.Items = app.allUnits.T;
                        
                    case 'Specific Volume - v'  
                        %app.UnitProp2DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};                        app.UnitProp2DropDown.Items = app.allUnits.v;
                        app.UnitProp2DropDown.Items = app.allUnits.v;
                    
                    case 'Internal Energy - u'
                        %app.UnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};                        app.UnitProp2DropDown.Items = app.allUnits.v;
                        app.UnitProp2DropDown.Items = app.allUnits.uh;
                    
                    case 'Enthalpy - h'    
                        %app.UnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.UnitProp2DropDown.Items = app.allUnits.uh;
                        
                    case 'Entropy - s'
                        %app.UnitProp2DropDown.Items = {'KJ/Kg * K'};
                        app.UnitProp2DropDown.Items = app.allUnits.s;
                        
                    case 'x'
                        app.UnitProp2DropDown.Items = {'No Unit'};
                end
        end

        % Value changed function: ProPropertyNo1DropDown
        function ProPropertyNo1DropDownValueChanged(app, event)
            
            % First Property DropDown
            
            app.ProPropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Internal Energy - u','Enthalpy - h','Entropy - s','x'};
            Prop1 = app.ProPropertyNo1DropDown.Value;
            app.ProUnitProp2DropDown.Items = {};

                switch Prop1
                    
                    case 'Select'
                        app.ProUnitProp1DropDown.Items = {};
                       
                    case 'Pressure'
                        %app.ProUnitProp1DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
                        app.ProUnitProp1DropDown.Items = app.allUnits.P;
                        
                        app.ProPropertyNo2DropDown.Items(2) = [];
                    
                    case 'Temperature'  
                        %app.ProUnitProp1DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
                        app.ProUnitProp1DropDown.Items = app.allUnits.T;
                        
                        app.ProPropertyNo2DropDown.Items(3) = [];
                        
                    case 'Specific Volume - v'  
                        %app.ProUnitProp1DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};
                        app.ProUnitProp1DropDown.Items = app.allUnits.v;
                        
                        app.ProPropertyNo2DropDown.Items(4) = [];                                     
                    
                    case 'Internal Energy - u'
                        %app.ProUnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.ProUnitProp1DropDown.Items = app.allUnits.uh;
                        
                        app.ProPropertyNo2DropDown.Items(5) = []; 
                    
                    case 'Enthalpy - h'
                        %app.ProUnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
                        app.ProUnitProp1DropDown.Items = app.allUnits.uh;
                        
                        app.ProPropertyNo2DropDown.Items(6) = [];
                 
                    case 'Entropy - s'
                        %app.ProUnitProp1DropDown.Items = {'KJ/Kg * S'};
                        app.ProUnitProp1DropDown.Items = app.allUnits.s;
                        
                        app.ProPropertyNo2DropDown.Items(7) = [];
                        
                    case 'x'
                        app.ProUnitProp1DropDown.Items = {'No Unit'};
                        app.ProPropertyNo2DropDown.Items(8) = []; 
                end
            
        end

        % Value changed function: ProPropertyNo2DropDown
        function ProPropertyNo2DropDownValueChanged(app, event)
  
            Prop2 = app.ProPropertyNo2DropDown.Value;
            
                        
            switch Prop2
                
                case 'Select'
                    app.ProUnitProp2DropDown.Items = {};
                    
                case 'Pressure'
                    %app.ProUnitProp2DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
                    app.ProUnitProp2DropDown.Items = app.allUnits.P;
                    
                case 'Temperature'
                    %app.ProUnitProp2DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
                    app.ProUnitProp2DropDown.Items = app.allUnits.T;
                    
                case 'Specific Volume - v'
                    %app.ProUnitProp2DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};
                    app.ProUnitProp2DropDown.Items = app.allUnits.v;
                    
                case 'Internal Energy - u'
                    %app.ProUnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};
                    app.ProUnitProp2DropDown.Items = app.allUnits.uh;
                    
                case 'Enthalpy - h'
                    %app.ProUnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};
                    app.ProUnitProp2DropDown.Items = app.allUnits.uh;
                    
                case 'Entropy - s'
                    %app.ProUnitProp2DropDown.Items = {'KJ/Kg * S'};
                    app.ProUnitProp2DropDown.Items = app.allUnits.uh;
                    
                case 'x'
                    app.ProUnitProp2DropDown.Items = {'No Unit'};
                    
            end


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

%             format(app.UITable,'long')
            
            % Table List
            %app.UITable.ColumnName = {'Phase','P [bar]',['T [', char(0176), 'C]'],'v [m3/Kg]','u [KJ/Kg]','h [KJ/Kg]','s [KJ/Kg * K]','x'};
            app.UITable.ColumnName = {'Phase',['P ',app.defautUnits.P],['T ', char(0176), app.defautUnits.T],['v ',app.defautUnits.v],['u ',app.defautUnits.uh],['h ',app.defautUnits.uh],['s ',app.defautUnits.s],'x'};
            
            
            %HERE This needs to change to reflect new units
            
            % First Property
            Property1 = app.PropertyNo1DropDown.Value;
            Value1 = app.Prop1EditField.Value;
            
            switch Property1
                
                case 'Pressure'
                    Prop1 = 'P';
                    %app.UITable.ColumnName{2}=  ['P [',app.UnitProp1DropDown.Value,']'];
                    
                case 'Temperature'
                    Prop1 = 'T';
                    %app.UITable.ColumnName{3}=  ['T [',app.UnitProp1DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop1 = 'v';
                    %app.UITable.ColumnName{4}=  ['v [',app.UnitProp1DropDown.Value,']'];
                case 'Internal Energy - u'
                    Prop1 = 'u';
                    %app.UITable.ColumnName{5}=  ['u [',app.UnitProp1DropDown.Value,']'];
                case 'Enthalpy - h'
                    Prop1 = 'h';
                    %app.UITable.ColumnName{6}=  ['h [',app.UnitProp1DropDown.Value,']'];
                
                case 'Entropy - s'
                    Prop1 = 's'; 
                    %app.UITable.ColumnName{6}=  ['s [',app.UnitProp1DropDown.Value,']'];

                case 'x'
                    Prop1 = 'x';


            end
            
            
            % Second Property
            Property2 = app.PropertyNo2DropDown.Value;
            Value2 = app.Prop2EditField.Value;
                    
            switch Property2
                %OVERRIDE - instead of changing units based on what is
                %selected, this will always output in the default book units
                case 'Pressure'
                    Prop2 = 'P';
                    %app.UITable.ColumnName{2}=  ['P [',app.UnitProp2DropDown.Value,']'];
                    
                case 'Temperature'
                    Prop2 = 'T';
                     %app.UITable.ColumnName{3}=  ['T [',app.UnitProp2DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop2 = 'v';
                    %app.UITable.ColumnName{4}=  ['v [',app.UnitProp2DropDown.Value,']'];
                case 'Internal Energy - u'
                    Prop2 = 'u';
                     %app.UITable.ColumnName{5}=  ['u [',app.UnitProp2DropDown.Value,']'];
                case 'Enthalpy - h'
                    Prop2 = 'h';
                     %app.UITable.ColumnName{6}=  ['h [',app.UnitProp2DropDown.Value,']'];
                case 'Entropy - s'
                    Prop2 = 's';
                    %app.UITable.ColumnName{6}=  ['s [',app.UnitProp1DropDown.Value,']'];
                case 'x'
                    Prop2 = 'x';
            end
            
%             Value1
%             Value2
%             
            
            % Before state identification, convert units
            % this needs to happen before the interpolation because the
            % property tables are in the default units
            Value1 = unitConvertFcn(Value1,app.UnitProp1DropDown.Value,'default');
            %testNew = unitConvertFcn(Value1,app.UnitProp1DropDown.Value,'default')
            Value2 = unitConvertFcn(Value2,app.UnitProp2DropDown.Value,'default');
            
            
           % State Identification
           if strcmp(Prop1,'x') || strcmp(Prop2,'x')
           State = 'Saturated';   
           else
           SampleData= StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
           State = SampleData.State;
           end 
     
      
               switch State
               
               case 'Saturated'
                   
                   if strcmp(Prop1,'x') || strcmp(Prop2,'x')   
                       if strcmp(Prop1,'x')
                       SatState = XSaturated(Value1,Prop2,Value2,app.Table);
                       else
                       SatState = XSaturated(Value2,Prop1,Value1,app.Table);    
                       end                
                       PropertyData = {'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.s,SatState.x};
                   else
                       SatState = StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
                       PropertyData = {'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.s,SatState.x};
                       
                   end
                   
                case 'SuperHeat'
                   
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SuperState = SuperHeat(Value1,Prop2,Value2,app.Table);
                        else
                            SuperState = SuperHeat(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,SuperState.s,'N/A'};
                    else
                        SuperState = SuperHeatR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,SuperState.s,'N/A'};     
                    end
                                             
                case 'SubCooled'
    
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SubState = SubCooled(Value1,Prop2,Value2,app.Table);
                        else
                            SubState = SubCooled(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,SubState.s,'N/A'};
                    else
                        SubState = SubCooledR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,SubState.s,'N/A'};
                    end
                                      
               end
               
               if strcmp(State,'Saturated')
                   
                   if SatState.x == 1
                       PropertyData{1} = 'Saturated Vapor';
                   elseif SatState.x ==0
                       PropertyData{1} = 'Saturated Liquid';
                   else
                       PropertyData{1} = 'Mixure';
                   end
                   
               end
               
               
               %now, before we pass it back to the table, we need to
               %convert back to the input units
               %PropertyData
               
               %PropertyData = {'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,SubState.s,'NaN'};
               
               %PropertyData{2} = unitConvertFcn(PropertyData{2},'default')


%                   Columns 1 through 5
% 
%     {'SubCooled'}    {[68.9476]}    {[200]}    {[0.0012]}    {[847.3914]}
% 
%   Columns 6 through 8
% 
%     {[855.3590]}    {[2.3210]}    {'NaN'}
%                
%                
%                
               
               app.UITable.Data = PropertyData;


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            %this is analogous to the calculateButtonPushed?
            %yes, because it is Add / Update
        
            % Table List
            %app.ProStateUITable.ColumnName = {'Label','Phase','P [bar]',['T [', char(0176), 'C]'],'v [m3/Kg]','u [KJ/Kg]','h [KJ/Kg]','s [KJ/Kg * K]','x'};
            app.ProStateUITable.ColumnName = {'State Label','Phase',['P [',app.defautUnits.P, ']'],['T [', char(0176), app.defautUnits.T, ']'],['v [',app.defautUnits.v, ']'],['u [',app.defautUnits.uh, ']'],['h [',app.defautUnits.uh, ']'],['s [',app.defautUnits.s, ']'],'x'};
            %app.ProStateUITable_2.ColumnName = {'Phase',['P ',app.defautUnits.P],['T ', char(0176), app.defautUnits.T],['v ',app.defautUnits.v],['u ',app.defautUnits.uh],['h ',app.defautUnits.uh],['s ',app.defautUnits.s],'x'};
            
            %happens later
%             Property1 = app.ProPropertyNo1DropDown.Value
%             Value1 = app.ProProp1EditField.Value
%             
            
            % First Property
            
            if app.ProcessCheckBox.Value
                
                Opt = app.ProcessDropDown.Value;
                
                switch Opt
                    
                    %all this is not yet implemented
                    case 'Isobar'
                    %case app.Processes{1}
                        Property1 = 'Pressure';
                        Value1 = app.ProStateUITable.Data{end,3};
                        
                    case 'Isotherm'
                    %case app.Processes{2}    
                        Property1 = 'Temperature';
                        Value1 = app.ProStateUITable.Data{end,4};
                        
                    case 'Constant Volume'
                    %case app.Processes{3}  
                        Property1 = 'Specific Volume - v';
                        Value1 = app.ProStateUITable.Data{end,5};
                        
                end

                
            else
                
                Property1 = app.ProPropertyNo1DropDown.Value;
                Value1 = app.ProProp1EditField.Value;
                
            end

%                 app.ProPropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Internal Energy - u','Enthalpy - h','Entropy - s','x'};
                      
            
            switch Property1
                
                case 'Pressure'
                    Prop1 = 'P';
                    %app.ProStateUITable.ColumnName{3}=  ['P [',app.ProUnitProp1DropDown.Value,']'];
                    
                case 'Temperature'
                    Prop1 = 'T';
                    %app.ProStateUITable.ColumnName{4}=  ['T [',app.ProUnitProp1DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop1 = 'v';
                    %app.ProStateUITable.ColumnName{5}=  ['v [',app.ProUnitProp1DropDown.Value,']'];
                    
                case 'Internal Energy - u'
                    Prop1 = 'u';
                    %app.ProStateUITable.ColumnName{6}=  ['u [',app.ProUnitProp1DropDown.Value,']'];
                    
                case 'Enthalpy - h'
                    Prop1 = 'h';
                    %app.ProStateUITable.ColumnName{7}=  ['h [',app.ProUnitProp1DropDown.Value,']'];
                    
                case 'Entropy - s'
                    Prop1 = 's';
                    %app.ProStateUITable.ColumnName{8}=  ['s [',app.ProUnitProp1DropDown.Value,']'];
                    
                case 'x'
                    Prop1 = 'x';

            end

            % Second Property
            Property2 = app.ProPropertyNo2DropDown.Value;
            Value2 = app.ProProp2EditField.Value;
                    
            switch Property2
                
                case 'Pressure'
                    Prop2 = 'P';
                    %app.ProStateUITable.ColumnName{3}=  ['P [',app.ProUnitProp2DropDown.Value,']'];
                    
                case 'Temperature'
                    Prop2 = 'T';
                     %app.ProStateUITable.ColumnName{4}=  ['T [',app.ProUnitProp2DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop2 = 'v';
                    %app.ProStateUITable.ColumnName{5}=  ['v [',app.ProUnitProp2DropDown.Value,']'];
                case 'Internal Energy - u'
                    Prop2 = 'u';
                     %app.ProStateUITable.ColumnName{6}=  ['u [',app.ProUnitProp2DropDown.Value,']'];
                case 'Enthalpy - h'
                    Prop2 = 'h';
                     %app.ProStateUITable.ColumnName{7}=  ['h [',app.ProUnitProp2DropDown.Value,']'];
                case 'Entropy - s'
                    Prop2 = 's';
                    %app.ProStateUITable.ColumnName{8}=  ['s [',app.ProUnitProp2DropDown.Value,']'];
                    
                case 'x'
                    Prop2 = 'x';
            end
            
            
            % Before state identification, convert units
            % this needs to happen before the interpolation because the
            % property tables are in the default units
            Value1 = unitConvertFcn(Value1,app.ProUnitProp1DropDown.Value,'default');
            %testNew = unitConvertFcn(Value1,app.UnitProp1DropDown.Value,'default')
            Value2 = unitConvertFcn(Value2,app.ProUnitProp2DropDown.Value,'default');


           % State Identification
           if strcmp(Prop1,'x') || strcmp(Prop2,'x')
           State = 'Saturated';   
           else
           
           %Prop1
           %Value1
           %Prop2
           %Value2
               
           SampleData= StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
           State = SampleData.State;
           end 
     
             StateLable = app.StateLablel.Value;
             
               switch State
                
               case 'Saturated'
                   
                   if strcmp(Prop1,'x') || strcmp(Prop2,'x')   
                       if strcmp(Prop1,'x')
                       SatState = XSaturated(Value1,Prop2,Value2,app.Table);
                       else
                       SatState = XSaturated(Value2,Prop1,Value1,app.Table);    
                       end                
                       PropertyData = {StateLable,'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.s,SatState.x};
                   else
                       SatState = StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
                       PropertyData = {StateLable,'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.s,SatState.x};
                       
                   end
                   
                case 'SuperHeat'
                   
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SuperState = SuperHeat(Value1,Prop2,Value2,app.Table);
                        else
                            SuperState = SuperHeat(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {StateLable,'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,SuperState.s,'N/A'};
                    else
                        SuperState = SuperHeatR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {StateLable,'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,SuperState.s,'N/A'};     
                    end
                                             
                case 'SubCooled'
                    
                    if strcmp(Prop1,'P') || strcmp(Prop2,'P')
                        if strcmp(Prop1,'P')
                            SubState = SubCooled(Value1,Prop2,Value2,app.Table);
                        else
                            SubState = SubCooled(Value2,Prop1,Value1,app.Table);
                        end
                        PropertyData = {StateLable,'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,SubState.s,'N/A'};
                    else
                        SubState = SubCooledR(Prop1,Value1,Prop2,Value2,app.Table);
                        PropertyData = {StateLable,'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,SubState.s,'N/A'};
                    end
                      
               end
               
               if strcmp(State,'Saturated')
                   
                   if SatState.x == 1
                       PropertyData{2} = 'Saturated Vapor';
                   elseif SatState.x ==0
                       PropertyData{2} = 'Saturated Liquid';
                   else
                       PropertyData{2} = 'Mixure';
                   end
                   
               end
               
               
               IsUpdate = false;
               
               if isempty(app.ProStateUITable.Data)
                   app.ProStateUITable.Data = [app.ProStateUITable.Data;PropertyData];
               else
                   for i = 1:size(app.ProStateUITable.Data,1)
                       if strcmp(StateLable,app.ProStateUITable.Data{i,1})
                           app.ProStateUITable.Data(i,:) = PropertyData;
                           IsUpdate = true;
                           break
                       end
                   end
                   
                   if ~IsUpdate  
                       app.ProStateUITable.Data = [app.ProStateUITable.Data;PropertyData];                
                   end
                   
               end
               
               
               app.StartingStateDropDown.Items = {'Select',app.ProStateUITable.Data{:,1}};
               
%                if app.ProcessCheckBox.Value
%                    ProcessData = {app.ProcessDropDown.Value,[num2str(app.ProStateUITable.Data{end-1,1}), ' to ',num2str(app.ProStateUITable.Data{end,1}) ]};  
%                else
%                ProcessData = {};
%                end
%                
%                app.ProUITable.Data =  [app.ProUITable.Data; ProcessData];


            StartingList = app.ProStateUITable.Data(:,1);
            app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];


            %get the current display data from the table in the states tab
            %set the display data in the process tab to match
            %this keeps the up to data always
            %app.ProStateUITable_2.DisplayData = app.ProStateUITable.DisplayData;
            %app.ProStateUITable_2.Data = app.ProStateUITable.Data;
            
            %fprintf("THE DATA HAS CHANGED");


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            cla(app.UIAxes,'reset')
            cla(app.Plot3D,'reset')
            grid(app.Plot3D,'on')
            Opt = app.PlotDropDown.Value;
            %plotType = app.PlotDropDown.Value;
            %app.plotTypes = {'P - V', 'T - V', 'T - S'};


             %%-------------------------- 3D Plot Section =============================%%
%             surf(app.DataPVT.Sat.v,app.DataPVT.Sat.T,app.DataPVT.Sat.P,'FaceColor',[0.6078,0.7882,0.8784],'EdgeColor','none','Parent',app.Plot3D);
            hold(app.Plot3D,'on')
            title(app.Plot3D,'PVT')
            xlabel(app.Plot3D,app.ProStateUITable.ColumnName{5})
            ylabel(app.Plot3D,app.ProStateUITable.ColumnName{4})
            zlabel(app.Plot3D,app.ProStateUITable.ColumnName{3})
            view(app.Plot3D,[45 45])
            
            plot3(app.Table.Sat.vf,app.Table.Sat.T,app.Table.Sat.P,'-r','Parent',app.Plot3D)
            plot3(app.Table.Sat.vg,app.Table.Sat.T,app.Table.Sat.P,'-r','Parent',app.Plot3D)
%             surf(app.DataPVT.Sup.v,app.DataPVT.Sup.T,app.DataPVT.Sup.P,'FaceColor',[ 0.8118,0.9020,0.9255],'EdgeColor','none','Parent',app.Plot3D);
%             surf(app.DataPVT.Sub.v,app.DataPVT.Sub.T,app.DataPVT.Sub.P,'FaceColor',[ 0.3922,0.5843,0.7647],'EdgeColor','none','Parent',app.Plot3D);
%             surf(app.DataPVT.SV.v,app.DataPVT.SV.T,app.DataPVT.SV.P,'FaceColor',[0.196,0.5961,0.7647],'EdgeColor','none','Parent',app.Plot3D);

            
            set(app.Plot3D,'XScale','log')
%             set(app.Plot3D,'YScale','log')
            
            %%-------------------------- Process Plot Section =============================%%
            
            % Process Data - Loop over Selected Processes
            
            if app.AllProcessesCheckBox.Value
                ProcessList = app.ProcessListBox.Items;
            else
                 ProcessList = app.ProcessListBox.Value;
            end
           
            %numel(ProcessList)
            
            for k = 1:numel(ProcessList)
                
                ProcLabel = ProcessList{k};
                
                
                % Detecting the Process
                for i=1:size(app.ProUITable.Data,1)
                    if strcmp(ProcLabel,app.ProUITable.Data{i,1})
                        Start = app.ProUITable.Data{i,2};
                        Final = app.ProUITable.Data{i,3};
                        ProcType = app.ProUITable.Data{i,end};
                        break
                    end
                end
                
                % Detecting the Starting State of the Process
                for i=1:size(app.ProStateUITable.Data,1)
                    if strcmp(Start,app.ProStateUITable.Data{i,1})
                        vStart = app.ProStateUITable.Data{i,5};
                        PStart = app.ProStateUITable.Data{i,3};
                        TStart = app.ProStateUITable.Data{i,4};
                        sStart = app.ProStateUITable.Data{i,end-1};
                        
                        break
                    end
                end
                
                % Detectin the Final State of the Process
                for i=1:size(app.ProStateUITable.Data,1)
                    if strcmp(Final,app.ProStateUITable.Data{i,1})
                        vFinal = app.ProStateUITable.Data{i,5};
                        PFinal = app.ProStateUITable.Data{i,3};
                        TFinal = app.ProStateUITable.Data{i,4};
                        sFinal = app.ProStateUITable.Data{i,end-1};
                        
                        break
                    end
                end
                
                % Process Line(States) Calculation
                switch ProcType
                    
                    %case 'Isobar'
                    case app.Processes{1}  
                        %ProcData = ProcIsoBarLine(PStart,vStart,vFinal,app.Table);
                        ProcData = ProcIsoLine('P',PStart,vStart,vFinal,app.Table,app.Critical)
                    
                    %case 'Isotherm'
                    case app.Processes{2}  
                        %ProcData = ProcIsoThermLine(TStart,vStart,vFinal,app.Table);
                        ProcData = ProcIsoLine('T',TStart,vStart,vFinal,app.Table,app.Critical);

                    %case 'Constant Volume'
                    case app.Processes{3}  
                        %ProcData = ProcConstVolLine(TStart,vStart,vFinal,app.Table);
                        %NOT YET IMPLEMENTED
                        ProcData = ProcIsoLine('v',vStart,PStart,PFinal,app.Table,app.Critical);
                    
                    %case 'Isentropic'
                    case app.Processes{4}
                        %ProcData = ProcIsentropicLine(TStart,vStart,vFinal,app.Table);
                        %NOT YET IMPLEMENTED
                        ProcData = ProcIsoLine('s',sStart,vStart,vFinal,app.Table,app.Critical);

                    %case 'Polytropic'
                        %NOT YET IMPLEMENTED

                    
                    case 'Unknown'
                        ProcData.v = [vStart,vFinal];
                        ProcData.T = [TStart,TFinal];
                        ProcData.P = [PStart,PFinal];
                        ProcData.s = [sStart,sFinal]
                end


                %Before plotting, convert units
                %be mindful, as always, that if this will convert all of
                %the values whether or not they make sense for that plot
                %for instance if L/kg was selected for a P-v plot, the
                %entropy would get converted from m3/kg to L/kg (or
                %multiplied by that factor). Again, shouldn't matter as it
                %isn't plotted

                %debug
%                 app.UnitPlotXDropDown
%                 a = ProcData.v'


                ProcData.v = unitConvertFcn(ProcData.v,'default',app.UnitPlotXDropDown.Value);
                ProcData.T = unitConvertFcn(ProcData.T,'default',app.UnitPlotYDropDown.Value);
                ProcData.P = unitConvertFcn(ProcData.P,'default',app.UnitPlotYDropDown.Value);
                ProcData.s = unitConvertFcn(ProcData.s,'default',app.UnitPlotXDropDown.Value);

                
                % Plot Process Line
                switch Opt
                    
%                     case 'V - P'
                    case app.plotTypes(1)
                        %fprintf("its plotting")

                        if strcmp(ProcType,'Unknown')
                            plot(ProcData.v,ProcData.P,'linewidth',app.processLineWidth,':k','Parent',app.UIAxes);
                            plot3(ProcData.v,ProcData.T,ProcData.P,'linewidth',app.processLineWidth,':k','Parent',app.Plot3D);
                        else
                            plot(ProcData.v,ProcData.P,'-k','linewidth',app.processLineWidth,'Parent',app.UIAxes);
                            plot3(ProcData.v,ProcData.T,ProcData.P,'-k','linewidth',app.processLineWidth,'Parent',app.Plot3D);

                        end
                        
                    %case 'V - T'
                    case app.plotTypes(2)
                        
                        if strcmp(ProcType,'Unknown')
                            plot(ProcData.v,ProcData.T,':k','linewidth',app.processLineWidth,'Parent',app.UIAxes);
                        else
                            plot(ProcData.v,ProcData.T,'-k','linewidth',app.processLineWidth,'Parent',app.UIAxes);
                        end
                        
                    %case 'T - S'
                    case app.plotTypes(3)
                        
                        if strcmp(ProcType,'Unknown')
                            plot(ProcData.s,ProcData.T,':k','linewidth',app.processLineWidth,'Parent',app.UIAxes);
                        else
                            plot(ProcData.s,ProcData.T,'-k','linewidth',app.processLineWidth,'Parent',app.UIAxes);
                        end
                        
                end
                
                hold(app.UIAxes,'on')

            end
     
            
        %%-------------------------- Iso-Lines Section =============================%%


            if app.IsoLinesCheckBox.Value

                % IsoBar Lines
                if app.IsoBarCheckBox.Value
                    
                    IsoValue = str2double(strsplit(app.IsoBarLineValue.Value,','));
                    
                    %Convert Units:
                    IsoValue = unitConvertFcn(IsoValue,app.UnitIsoBarDropDown.Value,'default');
                    %convert to default, get the iso line data, then
                    %convert back to plot it

                    for i = 1:numel(IsoValue)
                        
                        Out = IsoBarLineNew(IsoValue(i),app.Table);
                        hold(app.UIAxes,'on')
                        
                        %convert units to the figure's default units
                        Out.P = unitConvertFcn(Out.P,'default',app.UnitPlotYDropDown.Value);
                        Out.v = unitConvertFcn(Out.v,'default',app.UnitPlotXDropDown.Value);
                        Out.T = unitConvertFcn(Out.T,'default',app.UnitPlotYDropDown.Value);
                        Out.s = unitConvertFcn(Out.s,'default',app.UnitPlotXDropDown.Value);
                        %if the graph is a P-v, the temperature iso data
                        %will be converted to nonsense. I don't think this
                        %is an issue but something to be mindful of

                        % Plot Lines
                        switch Opt
                            %case 'V - T'
                            case app.plotTypes(2)
                                plot(Out.v,Out.T,'-g','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                            
                            %case 'V - P'
                            case app.plotTypes(1)
                                plot(Out.v,Out.P,'-g','linewidth',app.isoLineWidth,'Parent',app.UIAxes);

                            %case 'T - S'
                            case app.plotTypes(3)
                                plot(Out.s,Out.T,'-g','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                        end
                    end
                end


                % IsoTherm Lines
                if app.IsoThermCheckBox.Value
                    
                    IsoValue = str2double(strsplit(app.IsoThermValue.Value,','));
                    
                    %Convert Units:
                    IsoValue = unitConvertFcn(IsoValue,app.UnitIsoThermDropDown.Value,'default');
                    %convert to default, get the iso line data, then
                    %convert back to plot it

                    for i = 1:numel(IsoValue)
                        
                        Out = IsoThermLine(IsoValue(i),app.Table);
                        hold(app.UIAxes,'on')
                        
                        Out.P = unitConvertFcn(Out.P,'default',app.UnitPlotYDropDown.Value);
                        Out.v = unitConvertFcn(Out.v,'default',app.UnitPlotXDropDown.Value);
                        Out.T = unitConvertFcn(Out.T,'default',app.UnitPlotYDropDown.Value);
                        Out.s = unitConvertFcn(Out.s,'default',app.UnitPlotXDropDown.Value);


                        % Plot Lines
                        switch Opt
                            %case 'V - T'
                            case app.plotTypes(2)
                                plot(Out.v,Out.T,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                            %case 'V - P'
                            case app.plotTypes(1)
                                plot(Out.v,Out.P,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                            %case 'T - S'
                            case app.plotTypes(3)
                                plot(Out.s,Out.T,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                        end
                    end
                end
                
                % Constant Volume Lines
                if app.ConstantVolCheckBox.Value
                    
                    IsoValue = str2double(strsplit(app.ConstantVolValue.Value,','));
                    
                    %Convert Units:
                    IsoValue = unitConvertFcn(IsoValue,app.UnitIsoVolDropDown.Value,'default');
                    %convert to default, get the iso line data, then
                    %convert back to plot it


                    for i = 1:numel(IsoValue)
                        
                        Out = ConstVolLine(IsoValue(i),app.Table,app.Critical);
                        
                        Out.P = unitConvertFcn(Out.P,'default',app.UnitPlotYDropDown.Value);
                        Out.v = unitConvertFcn(Out.v,'default',app.UnitPlotXDropDown.Value);
                        Out.T = unitConvertFcn(Out.T,'default',app.UnitPlotYDropDown.Value);
                        Out.s = unitConvertFcn(Out.s,'default',app.UnitPlotXDropDown.Value);
                        %a = Out.s'
                        %b = Out.T'

                        %size(a)
                        %size(b)

                        hold(app.UIAxes,'on')
                        
                        % Plot Lines
                        switch Opt
                            %case 'V - T'
                            case app.plotTypes(2)
                                plot(Out.v,Out.T,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                %plot([2,3],[6,7],'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes)
                                %plot(Out.s,Out.T,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                            %case 'V - P'
                            case app.plotTypes(1)
                                plot(Out.v,Out.P,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                            %case 'T - S'
                            case app.plotTypes(3)
                                plot(Out.s,Out.T,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                %plot(Out.s,Out.T,'Parent',app.UIAxes);%,'linewidth',app.isoLineWidth,'-r','Parent',app.UIAxes);
                        end
                    end
                end
                
                % Isentropic Lines
                if app.IsentropicCheckBox.Value
                    
                    IsoValue = str2double(strsplit(app.IsentropicValue.Value,','));
                    
                    %Convert Units:
                    IsoValue = unitConvertFcn(IsoValue,app.UnitIsentropicDropDown.Value,'default');
                    %convert to default, get the iso line data, then
                    %convert back to plot it


                    for i = 1:numel(IsoValue)
                        
                        Out = IsentropicLine(IsoValue(i),app.Table,app.Critical);

                        Out.P = unitConvertFcn(Out.P,'default',app.UnitPlotYDropDown.Value);
                        Out.v = unitConvertFcn(Out.v,'default',app.UnitPlotXDropDown.Value);
                        Out.T = unitConvertFcn(Out.T,'default',app.UnitPlotYDropDown.Value);
                        Out.s = unitConvertFcn(Out.s,'default',app.UnitPlotXDropDown.Value);


                        hold(app.UIAxes,'on')
                        
                        % Plot Lines
                        switch Opt
                            %case 'T - V'
                            case app.plotTypes(2)
                                plot(Out.v,Out.T,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                %case 'V - P'
                            case app.plotTypes(1)
                                plot(Out.v,Out.P,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                            %case 'T - S'
                            case app.plotTypes(3)
                                plot(Out.s,Out.T,'-r','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                
                        end
                    end
                end
   

            end


            switch Opt
                
                
                %case 'V - T'
                case app.plotTypes(2)
     
                    %plot(app.Table.Sat.vf,app.Table.Sat.T,'-b',app.Table.Sat.vg,app.Table.Sat.T,'-b','Parent',app.UIAxes);
                    
                    %% convert units, directly before plotting
                    vf = unitConvertFcn(app.Table.Sat.vf,'default',app.UnitPlotXDropDown.Value);
                    vg = unitConvertFcn(app.Table.Sat.vg,'default',app.UnitPlotXDropDown.Value);
                    temp = unitConvertFcn(app.Table.Sat.T,'default',app.UnitPlotYDropDown.Value);
                    
                    plot(vf,temp,'-b',vg,temp,'-b','linewidth',app.lineWidth,'Parent',app.UIAxes);


                    %title(app.UIAxes,'V-T')
                    title(app.UIAxes,app.plotTypes(2))
%                     xlabel(app.UIAxes,app.ProStateUITable.ColumnName{5})
%                     ylabel(app.UIAxes,app.ProStateUITable.ColumnName{4})
%                     
                    xlabel(app.UIAxes,['v [', app.UnitPlotXDropDown.Value,']'])
                    ylabel(app.UIAxes,['T [', app.UnitPlotYDropDown.Value,']'])
                    
                    hold(app.UIAxes,'on')
                    

                    %convert before plotting the states
                    app.proStates = zeros(size(app.ProStateUITable.Data,1),2);
                    for i = 1:size(app.ProStateUITable.Data,1)
                        app.proStates(i,1) = unitConvertFcn(app.ProStateUITable.Data{i,5},'default',app.UnitPlotXDropDown.Value);
                        app.proStates(i,2) = unitConvertFcn(app.ProStateUITable.Data{i,4},'default',app.UnitPlotYDropDown.Value);
                    
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,app.proStates(i,1),app.proStates(i,2),'Ok','MarkerFaceColor','k')
                        text(app.UIAxes,app.proStates(i,1),app.proStates(i,2),['  ' app.ProStateUITable.Data{i,1}])
                    
                    end
                 
                %case 'V - P'
                case app.plotTypes(1)
                    %plot(app.Table.Sat.vf,app.Table.Sat.P,'-b',app.Table.Sat.vg,app.Table.Sat.P,'-b','Parent',app.UIAxes);

                    %% convert units, directly before plotting
                    vf = unitConvertFcn(app.Table.Sat.vf,'default',app.UnitPlotXDropDown.Value);
                    vg = unitConvertFcn(app.Table.Sat.vg,'default',app.UnitPlotXDropDown.Value);
                    press = unitConvertFcn(app.Table.Sat.P,'default',app.UnitPlotYDropDown.Value);
                    
                    plot(vf,press,'-b',vg,press,'-b','linewidth',app.lineWidth,'Parent',app.UIAxes);
                    

                    %title(app.UIAxes,'V-P')
                    title(app.UIAxes,app.plotTypes(1))
                    %HERE
                    %app.UITable.ColumnName
                    %xlabel(app.UIAxes,strcat(app.ProStateUITable.ColumnName{5}, app.UITable.ColumnName{4}))
%                     xlabel(app.UIAxes,strcat(app.ProStateUITable.ColumnName{5}, ' m^3/kg'))
%                     ylabel(app.UIAxes,strcat(app.ProStateUITable.ColumnName{3}, ' kPa'))
%                     
                    xlabel(app.UIAxes,['v [', app.UnitPlotXDropDown.Value,']'])
                    ylabel(app.UIAxes,['P [', app.UnitPlotYDropDown.Value,']'])
                    
                    hold(app.UIAxes,'on')
%                     legend(app.UIAxes, ['200', char(0176), ' C'], ['230', char(0176), ' C'], 'Water - Solid Liquid Transition')
%                     
%                     title(app.UIAxes, "Pressure vs. Specific Volume for Water")
%                     
                    
                    %a = app.ProStateUITable.Data{:,5}
                    %b = app.ProStateUITable.Data{:,3}
                    %convert before plotting the states
                    app.proStates = zeros(size(app.ProStateUITable.Data,1),2);
                    
                    %d = app.proStates(:,1)
                    %e = app.proStates(:,2)

                    for i = 1:size(app.ProStateUITable.Data,1)
                        app.proStates(i,1) = unitConvertFcn(app.ProStateUITable.Data{i,5},'default',app.UnitPlotXDropDown.Value);
                        app.proStates(i,2) = unitConvertFcn(app.ProStateUITable.Data{i,3},'default',app.UnitPlotYDropDown.Value);
                    
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,app.proStates(i,1),app.proStates(i,2),'Ok','MarkerFaceColor','k')
                        text(app.UIAxes,app.proStates(i,1),app.proStates(i,2),['  ' app.ProStateUITable.Data{i,1}])
                        
                        % 3D Plot
                        plot3(app.Plot3D,app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,4},app.ProStateUITable.Data{i,3},'Ok','MarkerFaceColor','k')
                        text(app.Plot3D,app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,4},app.ProStateUITable.Data{i,3},['  ' app.ProStateUITable.Data{i,1}])
                        
                        
                    end
                    
                    
                %case 'T - S'
                case app.plotTypes(3)
                    
                    %% convert units, directly before plotting
                    sf = unitConvertFcn(app.Table.Sat.sf,'default',app.UnitPlotXDropDown.Value);
                    sg = unitConvertFcn(app.Table.Sat.sg,'default',app.UnitPlotXDropDown.Value);
                    temp = unitConvertFcn(app.Table.Sat.T,'default',app.UnitPlotYDropDown.Value);
                    
                    plot(sf,temp,'-b',sg,temp,'-b','linewidth',app.lineWidth,'Parent',app.UIAxes);


                    %plot(app.Table.Sat.sf,app.Table.Sat.T,'-b',app.Table.Sat.sg,app.Table.Sat.T,'-b','Parent',app.UIAxes);
                    
                    %title(app.UIAxes,'T-S')
                    title(app.UIAxes,app.plotTypes(3))
%                     xlabel(app.UIAxes,app.ProStateUITable.ColumnName{end-1})
%                     ylabel(app.UIAxes,app.ProStateUITable.ColumnName{4})
%                     
                    xlabel(app.UIAxes,['s [', app.UnitPlotXDropDown.Value,']'])
                    ylabel(app.UIAxes,['T [', app.UnitPlotYDropDown.Value,']'])
                    
                    hold(app.UIAxes,'on')
                    
                    
                    %convert before plotting the states
                    app.proStates = zeros(size(app.ProStateUITable.Data,1),2);
                    %proStates(:,1) = unitConvertFcn(app.ProStateUITable.Data{:,5},'default',app.UnitPlotXDropDown.Value);
                    %proStates(:,2) = unitConvertFcn(app.ProStateUITable.Data{:,3},'default',app.UnitPlotYDropDown.Value);
                    %not yet implemented

                    for i = 1:size(app.ProStateUITable.Data,1)
                        %a = app.ProStateUITable.Data{i,8}
                        %b = app.UnitPlotXDropDown.Value
                        
                        %indices here are 8 and 4 instead of 3 and 7
                        %because there is an extra column corresponding to
                        %the state label
                        app.proStates(i,1) = unitConvertFcn(app.ProStateUITable.Data{i,8},'default',app.UnitPlotXDropDown.Value);
                        app.proStates(i,2) = unitConvertFcn(app.ProStateUITable.Data{i,4},'default',app.UnitPlotYDropDown.Value);
                    
                        hold(app.UIAxes,'on')
                        %app.uiPlot = plot(app.UIAxes,app.ProStateUITable.Data{i,end-1},app.ProStateUITable.Data{i,4},'Ok','MarkerFaceColor','k');
                        %plot(app.UIAxes,app.ProStateUITable.Data{i,end-1},app.ProStateUITable.Data{i,4},'Ok','MarkerFaceColor','k');
                        %text(app.UIAxes,app.ProStateUITable.Data{i,end-1},app.ProStateUITable.Data{i,4},['  ' app.ProStateUITable.Data{i,1}])
                        
                        plot(app.UIAxes,app.proStates(i,1),app.proStates(i,2),'Ok','MarkerFaceColor','k')
                        text(app.UIAxes,app.proStates(i,1),app.proStates(i,2),['  ' app.ProStateUITable.Data{i,1}])
                        
                        % 3D Plot - NOT WORKING ATM
                        plot3(app.Plot3D,app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,4},app.ProStateUITable.Data{i,3},'Ok','MarkerFaceColor','k')
                        text(app.Plot3D,app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,4},app.ProStateUITable.Data{i,3},['  ' app.ProStateUITable.Data{i,1}])
                        

                    end


                case 'Select'
                    
                    cla(app.UIAxes,'reset')

            end

            %if ~strcmp(Opt,'T - S')
            if ~strcmp(Opt,app.plotTypes(3))
            set(app.UIAxes,'XScale','log')
            end
            
            %pause
            %print('PV Isotherms','-dpng')
            
            box(app.UIAxes,'on')
            set(app.UIAxes,'linewidth',app.axisWidth,'FontSize',app.fontSizeFigure)
            grid(app.UIAxes,'on')


        end

        % Value changed function: ProcessCheckBox
        function ProcessCheckBoxValueChanged(app, event)
          
            ProcessCheck = app.ProcessCheckBox.Value;
            
            switch  ProcessCheck
                
                
                case true
                    app.ProPropertyNo1DropDown.Enable = 0;
                    app.ProProp1EditField.Enable = 0;
                    app.ProUnitProp1DropDown.Enable = 0;
                    app.ProcessDropDown.Enable = 1;
                    app.PropertyNo1Label.Visible = 0;
                    app.Label.Text = 'Property';
                    
                    
                case false
                    app.ProPropertyNo1DropDown.Enable = 1;
                    app.ProProp1EditField.Enable = 1;
                    app.ProUnitProp1DropDown.Enable = 1;
                    app.ProcessDropDown.Enable = 0;
                    app.PropertyNo1Label.Visible = 1;
                    app.Label.Text = 'Property No.2';
                    
            end
            
            
        end

        % Value changed function: ProcessDropDown
        function ProcessDropDownValueChanged(app, event)
             ProcessCheck = app.ProcessCheckBox.Value;
            
            
             % First Property DropDown
             
             app.ProPropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h','x'};
             Prop = app.ProcessDropDown.Value;
             
             
             if ProcessCheck

                 switch Prop
                     
                     case 'Select'
                         app.ProUnitProp1DropDown.Items = {};
                         
                     case 'Isobar'
                         app.ProPropertyNo2DropDown.Items(2) = [];
                         
                     case 'Isotherm'
                         app.ProPropertyNo2DropDown.Items(3) = [];
                         
                     case 'Constant Volume'
                         app.ProPropertyNo2DropDown.Items(4) = [];
                         
                     case 'Specific Internal Energy - u'
                         app.ProPropertyNo2DropDown.Items(5) = [];
                         
                     case 'Specific Enthropy - h'
                         app.ProPropertyNo2DropDown.Items(6) = [];
                         
                     case 'x'
                         app.ProPropertyNo2DropDown.Items(7) = [];
                 end
                 
             end


        end

        % Value changed function: IsoLinesCheckBox
        function IsoLinesCheckBoxValueChanged(app, event)
            Flag = app.IsoLinesCheckBox.Value;
            
            if Flag
%                 app.IsoLinesList.Enable = 1;
                app.IsoBarLineValue.Enable = 1;
                
                app.IsoBarCheckBox.Enable = 1;
                
                app.IsoThermCheckBox.Enable = 1;
                app.IsoThermValue.Enable = 1;
                
                app.ConstantVolCheckBox.Enable = 1;
                app.ConstantVolValue.Enable = 1;
                
                app.IsentropicCheckBox.Enable = 1;
                app.IsentropicValue.Enable = 1;
                
            else
%                 app.IsoLinesList.Enable = 0;
                app.IsoBarLineValue.Enable = 0;
                
                
                app.IsoBarCheckBox.Enable = 0;
                                
                app.IsoThermCheckBox.Enable = 0;
                app.IsoThermValue.Enable = 0;
                
                app.ConstantVolCheckBox.Enable = 0;
                app.ConstantVolValue.Enable = 0;
                
                app.IsentropicCheckBox.Enable = 0;
                app.IsentropicValue.Enable = 0;
            end
            
        end

        % Cell selection callback: ProStateUITable
        function ProStateUITableCellSelection(app, event)
            app.StateInd = event.Indices;
          
                app.StateLablel.Value = app.ProStateUITable.Data{app.StateInd(1),1};

            
        end

        % Cell selection callback: ProUITable
        function ProUITableCellSelection(app, event)
            app.ProInd = event.Indices;
            app.ProcessLabelEditField.Value = app.ProUITable.Data{app.ProInd(1),1};

            app.StartingStateDropDown.Value = app.ProUITable.Data{app.ProInd(1),2};
            
            app.FinalStateDropDown.Items = app.StartingStateDropDown.Items;
            for i = 2:size(app.ProStateUITable.Data,1)    
                if strcmp(app.FinalStateDropDown.Items{i},app.StartingStateDropDown.Value)
                    app.FinalStateDropDown.Items(i)=[];
                    break
                end
            end
                app.FinalStateDropDown.Value= app.ProUITable.Data{app.ProInd(1),3};
                
            
        end

        % Value changed function: StartingStateDropDown
        function StartingStateDropDownValueChanged(app, event)

            app.FinalStateDropDown.Items = app.StartingStateDropDown.Items;
            
            for i = 2:size(app.ProStateUITable.Data,1)
                
                if strcmp(app.FinalStateDropDown.Items{i},app.StartingStateDropDown.Value)
                    app.FinalStateDropDown.Items(i)=[];
                    break
                end
                
            end

            
        end

        % Button pushed function: ProcessAddButton
        function ProcessAddButtonPushed(app, event)


            IsUpdate = false;
            
            I = zeros(1,size(app.ProUITable.Data,1));
            for i = 1:size(app.ProUITable.Data,1)
                if strcmp(app.StartingStateDropDown.Value,app.ProUITable.Data{i,2})
                    I(i) = i;
                end    
            end
            I(I==0) = [];
            for j = 1:numel(I)
                
                if strcmp(app.FinalStateDropDown.Value,app.ProUITable.Data{I(j),3})
                    
                    I_Index = I(j);
                    IsUpdate = true;
                    break
                end
            end
            
                
            if ~strcmp(app.ProcessDropDownNew.Value,'Select')
%                 ProcessName = app.UnknownProcessNameEditField.Value;
%             else
                ProcessName = app.ProcessDropDownNew.Value;
            end
            
            PropertyData = {app.ProcessLabelEditField.Value,app.StartingStateDropDown.Value,app.FinalStateDropDown.Value,ProcessName};
            
            if IsUpdate
                app.ProUITable.Data(I_Index,:) = PropertyData;
            else
                app.ProUITable.Data = [app.ProUITable.Data;PropertyData];
            end
                

%             % Make Process List 
%             app.ProcessLabelPlot.Items = {'Select',app.ProUITable.Data{:,1}};
            
            % Update ListBox
            app.ProcessListBox.Items = app.ProUITable.Data(:,1);


        end

        % Value changed function: ProcessDropDownNew
        function ProcessDropDownNewValueChanged(app, event)
%             value = app.ProcessDropDownNew.Value;
            
%             if strcmp(value,'Unknown')
%                 app.UnknownProcessNameEditField.Enable = 1;
% %                 app.ProcessNameLabel.Enable = 1;
%                 
%             else
%                 app.UnknownProcessNameEditField.Enable = 0;
% %                 app.ProcessNameLabel.Enable = 0;
%             end
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            

           %HERE is this hard-coded to only work with three states?
           for i  = 1:size(app.ProUITable.Data,1)             
               if strcmp(app.ProUITable.Data{i,2},app.ProStateUITable.Data{app.StateInd(1),1}) || strcmp(app.ProUITable.Data{i,3},app.ProStateUITable.Data{app.StateInd(1),1})        
                   app.ProUITable.Data(i,:)=[];
               end
           end
           
%            if all(~(strcmp(app.ProUITable.Data{i,2},app.ProStateUITable.Data{app.StateInd(1),1}) || strcmp(app.ProUITable.Data{i,3},app.ProStateUITable.Data{app.StateInd(1),1})))
%                fprintf("State to remove does not exist");
%            
%            end
           
           app.ProStateUITable.Data(app.StateInd(1),:)=[];
           app.StateLablel.Value = '';
           
           StartingList = app.ProStateUITable.Data(:,1);
           app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];
           
           
            % Update ListBox
            app.ProcessListBox.Items = app.ProUITable.Data(:,1);
           
    
        end

        % Button pushed function: ProRemoveButton
        function ProRemoveButtonPushed(app, event)
            
            app.ProUITable.Data(app.ProInd(1),:)=[];
            
            % Update ListBox
            app.ProcessListBox.Items = app.ProUITable.Data(:,1);
            
            
        end

        % Value changed function: IsoBarCheckBox
        function IsoBarCheckBoxValueChanged(app, event)
            value = app.IsoBarCheckBox.Value;
            
            if value ==1
                app.IsoBarLineValue.Enable = 1;
            else
                app.IsoBarLineValue.Enable = 0;
            end
            
        end

        % Value changed function: IsoThermCheckBox
        function IsoThermCheckBoxValueChanged(app, event)
            value = app.IsoThermCheckBox.Value;
            if value ==1
                app.IsoThermValue.Enable = 1;
            else
                app.IsoThermValue.Enable = 0;
            end
        end

        % Value changed function: ConstantVolCheckBox
        function ConstantVolCheckBoxValueChanged(app, event)
           
            value = app.ConstantVolCheckBox.Value;
            if value ==1
                app.ConstantVolValue.Enable = 1;
            else
                app.ConstantVolValue.Enable = 0;
            end
            
            
        end

        % Value changed function: IsentropicCheckBox
        function IsentropicCheckBoxValueChanged(app, event)
            value = app.IsentropicCheckBox.Value;            
            if value ==1
                app.IsentropicValue.Enable = 1;
            else
                app.IsentropicValue.Enable = 0;
            end
        end

        % Value changed function: UnitPlotXDropDown
        function UnitPlotXDropDownValueChanged(app, event)
            %newXUnits = app.UnitPlotXDropDown.Value;
            plotType = app.PlotDropDown.Value;
            
            switch plotType
                %update axis labels based on selected units
                case app.plotTypes(1)
                %P-V
                xlabel(app.UIAxes,['v ', app.UnitPlotXDropDown.Value])
                
                case app.plotTypes(2)
                %T-V
                xlabel(app.UIAxes,['v ', app.UnitPlotXDropDown.Value])
                
                case app.plotTypes(3)
                %T-S
                xlabel(app.UIAxes,['s ', app.UnitPlotXDropDown.Value])
                
                otherwise
                    fprintf("Plot type mismatch")
                    
            end
            
            
            %then just call plot button pushed and re-render everything
            app.PlotButtonPushed;
        end

        % Value changed function: UnitPlotYDropDown
        function UnitPlotYDropDownValueChanged(app, event)
            %newYUnits = app.UnitPlotYDropDown.Value;
            plotType = app.PlotDropDown.Value;
            
            switch plotType
                %update axis labels based on selected units
                case app.plotTypes(1)
                %P-V
                ylabel(app.UIAxes,['P ', app.UnitPlotYDropDown.Value])
                
                case app.plotTypes(2)
                %T-V
                ylabel(app.UIAxes,['T ', app.UnitPlotYDropDown.Value])
                
                case app.plotTypes(3)
                %T-S
                ylabel(app.UIAxes,['T ', app.UnitPlotYDropDown.Value])
                
                otherwise
                    fprintf("Plot type mismatch")
                    
            end
            
            
            %then just call plot button pushed and re-render everything
            app.PlotButtonPushed;
        end

        % Display data changed function: ProStateUITable
        function ProStateUITableDisplayDataChanged(app, event)
            newDisplayData = app.ProStateUITable.DisplayData;
            %get the current display data from the table in the states tab
            %set the display data in the process tab to match
            app.ProStateUITable_2.DisplayData = newDisplayData;
            
            
            fprintf("THE DATA HAS CHANGED");
            %alternatively, this has been moved to the add / update button
            %pushed callback
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            plotType = app.PlotDropDown.Value;
            
            %update unit dropdowns based on the plot type
            %plotTypes = {'P - V', 'T - V', 'T - S'};
            switch plotType
                
                case app.plotTypes(1)
                %P-V
                app.UnitPlotXDropDown.Items = app.allUnits.v;
                app.UnitPlotYDropDown.Items = app.allUnits.P;
                
                %update axis labels based on selected units
                xlabel(app.UIAxes,['v ', app.UnitPlotXDropDown.Value])
                ylabel(app.UIAxes,['P ', app.UnitPlotYDropDown.Value])
                
                case app.plotTypes(2)
                %T-V
                app.UnitPlotXDropDown.Items = app.allUnits.v;
                app.UnitPlotYDropDown.Items = app.allUnits.T;
                
                %update axis labels based on selected units
                xlabel(app.UIAxes,['v ', app.UnitPlotXDropDown.Value])
                ylabel(app.UIAxes,['T ', app.UnitPlotYDropDown.Value])
                
                case app.plotTypes(3)
                %T-S
                app.UnitPlotXDropDown.Items = app.allUnits.s;
                app.UnitPlotYDropDown.Items = app.allUnits.T;
                
                %update axis labels based on selected units
                xlabel(app.UIAxes,['s ', app.UnitPlotXDropDown.Value])
                ylabel(app.UIAxes,['T ', app.UnitPlotYDropDown.Value])
                
                otherwise
                    fprintf("Plot type mismatch")
                    
            end


        end

        % Value changed function: FinalStateDropDown
        function FinalStateDropDownValueChanged(app, event)
            %get the labels of each state in the process
            StartingStateLabel = app.StartingStateDropDown.Value;
            FinalStateLabel = app.FinalStateDropDown.Value;
            
            %get the properties of at each state
            
            %both of these still in cell arrays
            %app.ProStateUITable.Data(1,:) %gets the data in row 1
            %app.ProStateUITable.Data(:,1) %gets the data in column 1
            %app.ProStateUITable.Data{1,1}=='a' %only decelled values like
            %being checked, or strcmp'ed

            %run through the table to find the starting and final indices
            StartingStateIndex = 0;
            FinalStateIndex = 0;
            for i = 1:size(app.ProStateUITable.Data,1)
                %check if the state label matches the starting or final
                %state label
                if strcmp(app.ProStateUITable.Data{i,1},StartingStateLabel)
                    StartingStateIndex = i
                elseif strcmp(app.ProStateUITable.Data{i,1},FinalStateLabel)
                    FinalStateIndex = i
                end
                
                if StartingStateIndex + FinalStateIndex < 2
                    fprintf("no matching state labels")
                else
                    %get the data
                    %keep it in cells otherwise it all just breaks apart
                    %and that's no good
                    StartingStateData = app.ProStateUITable.Data(StartingStateIndex,:)
                    FinalStateData = app.ProStateUITable.Data(FinalStateIndex,:)
                    %app.ProStateUITable.Data(2,:)
                    
                    Process = ProcessDetect(StartingStateData,FinalStateData,app.processDetectTolerance)
                    
                    a = app.ProcessDropDownNew.Items
                    app.ProcessDropDownNew.Value = Process

                end

            end


            %StartingStateIndex = strcmp(app.ProStateUITable.Data{:,1},StartingStateLabel)
            %StartingStateData = app.ProStateUITable.Data

            %ProcessDetect(app.Table)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.StatesTab = uitab(app.TabGroup);
            app.StatesTab.Title = 'States';
            app.GridLayout5 = uigridlayout(app.StatesTab);
            app.ProPropertyNo1DropDown = uidropdown(app.GridLayout5);
            app.ProPropertyNo1DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Specific Volume - v', 'Internal Energy - u', 'Enthalpy - h', 'Entropy - s', 'x'};
            app.ProPropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @ProPropertyNo1DropDownValueChanged, true);
            app.ProPropertyNo1DropDown.Value = 'Select';
            app.ProPropertyNo2DropDown = uidropdown(app.GridLayout5);
            app.ProPropertyNo2DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Specific Volume - v', 'Internal Energy - u', 'Enthalpy - h', 'Entropy - s', 'x'};
            app.ProPropertyNo2DropDown.ValueChangedFcn = createCallbackFcn(app, @ProPropertyNo2DropDownValueChanged, true);
            app.ProPropertyNo2DropDown.Value = 'Select';
            app.ProProp1EditField = uieditfield(app.GridLayout5, 'numeric');
            app.ProProp2EditField = uieditfield(app.GridLayout5, 'numeric');
            app.ProStateUITable = uitable(app.GridLayout5);
            app.ProStateUITable.ColumnName = {'State Label'; 'Phase'; 'P'; 'T'; 'v'; 'u'; 'h'; 's'; 'x'};
            app.ProStateUITable.RowName = {};
            app.ProStateUITable.CellSelectionCallback = createCallbackFcn(app, @ProStateUITableCellSelection, true);
            app.ProStateUITable.DisplayDataChangedFcn = createCallbackFcn(app, @ProStateUITableDisplayDataChanged, true);
            app.ProUnitProp1DropDown = uidropdown(app.GridLayout5);
            app.ProUnitProp1DropDown.Items = {};
            app.ProUnitProp1DropDown.Value = {};
            app.ProUnitProp2DropDown = uidropdown(app.GridLayout5);
            app.ProUnitProp2DropDown.Items = {};
            app.ProUnitProp2DropDown.Value = {};
            app.TableAStatesListLabel = uilabel(app.GridLayout5);
            app.TableAStatesListLabel.Text = ' Table A - States List';
            app.AddButton = uibutton(app.GridLayout5, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add/Update';
            app.UnitLabel_2 = uilabel(app.GridLayout5);
            app.UnitLabel_2.Text = 'Unit';
            app.ProcessDropDown = uidropdown(app.GridLayout5);
            app.ProcessDropDown.Items = {'Select', 'Isobar', 'Isotherm', 'Constant Volume'};
            app.ProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcessDropDownValueChanged, true);
            app.ProcessDropDown.Enable = 'off';
            app.ProcessDropDown.Value = 'Select';
            app.ProcessCheckBox = uicheckbox(app.GridLayout5);
            app.ProcessCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessCheckBoxValueChanged, true);
            app.ProcessCheckBox.Enable = 'off';
            app.ProcessCheckBox.Text = ' Process';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = 'Property No.2';
            app.PropertyNo1Label = uilabel(app.GridLayout5);
            app.PropertyNo1Label.Text = 'Property No.1';
            app.RemoveButton = uibutton(app.GridLayout5, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.StateLabelEditField_2Label = uilabel(app.GridLayout5);
            app.StateLabelEditField_2Label.Text = 'State Label';
            app.StateLablel = uieditfield(app.GridLayout5, 'text');
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.GridLayout2_2 = uigridlayout(app.ProcessTab);
            app.ProUITable = uitable(app.GridLayout2_2);
            app.ProUITable.ColumnName = {'Process Label'; 'Start State'; 'Final State'; 'Process Type'};
            app.ProUITable.RowName = {};
            app.ProUITable.CellSelectionCallback = createCallbackFcn(app, @ProUITableCellSelection, true);
            app.ProcessDropDown_2Label = uilabel(app.GridLayout2_2);
            app.ProcessDropDown_2Label.Text = 'Process';
            app.ProcessDropDownNew = uidropdown(app.GridLayout2_2);
            app.ProcessDropDownNew.Items = {'Select', 'Isobar', 'Isotherm', 'Constant Volume', 'Isentropic', 'Unknown'};
            app.ProcessDropDownNew.ValueChangedFcn = createCallbackFcn(app, @ProcessDropDownNewValueChanged, true);
            app.ProcessDropDownNew.Value = 'Select';
            app.ProcessAddButton = uibutton(app.GridLayout2_2, 'push');
            app.ProcessAddButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAddButtonPushed, true);
            app.ProcessAddButton.Text = 'Add/Update';
            app.StartingStateDropDownLabel = uilabel(app.GridLayout2_2);
            app.StartingStateDropDownLabel.Text = 'Starting State';
            app.StartingStateDropDown = uidropdown(app.GridLayout2_2);
            app.StartingStateDropDown.Items = {'Select', '', 'Option 2', 'Option 3', 'Option 4'};
            app.StartingStateDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingStateDropDownValueChanged, true);
            app.StartingStateDropDown.Value = 'Select';
            app.FinalStateDropDownLabel = uilabel(app.GridLayout2_2);
            app.FinalStateDropDownLabel.Text = 'Final State';
            app.FinalStateDropDown = uidropdown(app.GridLayout2_2);
            app.FinalStateDropDown.Items = {'Select', 'Option 2', 'Option 3', 'Option 4'};
            app.FinalStateDropDown.ValueChangedFcn = createCallbackFcn(app, @FinalStateDropDownValueChanged, true);
            app.FinalStateDropDown.Value = 'Select';
            app.ProRemoveButton = uibutton(app.GridLayout2_2, 'push');
            app.ProRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @ProRemoveButtonPushed, true);
            app.ProRemoveButton.Text = 'Remove';
            app.ProcessLabelEditFieldLabel = uilabel(app.GridLayout2_2);
            app.ProcessLabelEditFieldLabel.Text = 'Process Label';
            app.ProcessLabelEditField = uieditfield(app.GridLayout2_2, 'text');
            app.ProcessListBox = uilistbox(app.GridLayout2_2);
            app.ProcessListBox.Items = {};
            app.ProcessListBox.Multiselect = 'on';
            app.ProcessListBox.Value = {};
            app.ProcessListBoxLabel = uilabel(app.GridLayout2_2);
            app.ProcessListBoxLabel.Text = 'Processes to Display';
            app.AllProcessesCheckBox = uicheckbox(app.GridLayout2_2);
            app.AllProcessesCheckBox.Text = 'All Processes';
            app.AllProcessesCheckBox.Value = true;
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout3 = uigridlayout(app.PlotsTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotDropDown = uidropdown(app.GridLayout3);
            app.PlotDropDown.Items = {'Select', 'V - P', 'V - T', 'T - S'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Value = 'Select';
            app.PlotButton = uibutton(app.GridLayout3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.IsoLinesCheckBox = uicheckbox(app.GridLayout3);
            app.IsoLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsoLinesCheckBoxValueChanged, true);
            app.IsoLinesCheckBox.Text = '  Iso Lines';
            app.IsoLinesCheckBox.Value = true;
            app.IsoBarLineValue = uieditfield(app.GridLayout3, 'text');
            app.IsoBarCheckBox = uicheckbox(app.GridLayout3);
            app.IsoBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsoBarCheckBoxValueChanged, true);
            app.IsoBarCheckBox.Text = '  Isobar Line';
            app.IsoThermCheckBox = uicheckbox(app.GridLayout3);
            app.IsoThermCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsoThermCheckBoxValueChanged, true);
            app.IsoThermCheckBox.Text = '  Isotherm Line';
            app.ConstantVolCheckBox = uicheckbox(app.GridLayout3);
            app.ConstantVolCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConstantVolCheckBoxValueChanged, true);
            app.ConstantVolCheckBox.Text = '  Cons. Volume';
            app.IsoThermValue = uieditfield(app.GridLayout3, 'text');
            app.ConstantVolValue = uieditfield(app.GridLayout3, 'text');
            app.IsentropicCheckBox = uicheckbox(app.GridLayout3);
            app.IsentropicCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsentropicCheckBoxValueChanged, true);
            app.IsentropicCheckBox.Text = '  Isentropic Line';
            app.IsentropicValue = uieditfield(app.GridLayout3, 'text');
            app.UnitPlotXDropDown = uidropdown(app.GridLayout3);
            app.UnitPlotXDropDown.Items = {};
            app.UnitPlotXDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitPlotXDropDownValueChanged, true);
            app.UnitPlotXDropDown.Value = {};
            app.UnitPlotYDropDown = uidropdown(app.GridLayout3);
            app.UnitPlotYDropDown.Items = {};
            app.UnitPlotYDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitPlotYDropDownValueChanged, true);
            app.UnitPlotYDropDown.Value = {};
            app.UnitPlotXDropDownLabel = uilabel(app.GridLayout3);
            app.UnitPlotXDropDownLabel.Text = 'X units';
            app.UnitPlotYDropDownLabel = uilabel(app.GridLayout3);
            app.UnitPlotYDropDownLabel.Text = 'Y units';
            app.UnitIsoBarDropDown = uidropdown(app.GridLayout3);
            app.UnitIsoBarDropDown.Items = {};
            app.UnitIsoBarDropDown.Value = {};
            app.UnitIsoThermDropDown = uidropdown(app.GridLayout3);
            app.UnitIsoThermDropDown.Items = {};
            app.UnitIsoThermDropDown.Value = {};
            app.UnitIsoVolDropDown = uidropdown(app.GridLayout3);
            app.UnitIsoVolDropDown.Items = {};
            app.UnitIsoVolDropDown.Value = {};
            app.UnitIsentropicDropDown = uidropdown(app.GridLayout3);
            app.UnitIsentropicDropDown.Items = {};
            app.UnitIsentropicDropDown.Value = {};
            app.DPlotTab = uitab(app.TabGroup);
            app.DPlotTab.Title = '3D Plot';
            app.GridLayout4 = uigridlayout(app.DPlotTab);
            app.Plot3D = uiaxes(app.GridLayout4);
            title(app.Plot3D, 'Title')
            xlabel(app.Plot3D, 'X')
            ylabel(app.Plot3D, 'Y')
            app.PropertyCalcoldTab = uitab(app.TabGroup);
            app.PropertyCalcoldTab.Title = 'Property Calc (old)';
            app.GridLayout2 = uigridlayout(app.PropertyCalcoldTab);
            app.PropertyNo1DropDownLabel = uilabel(app.GridLayout2);
            app.PropertyNo1DropDownLabel.Text = 'Property No.1';
            app.PropertyNo1DropDown = uidropdown(app.GridLayout2);
            app.PropertyNo1DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Specific Volume - v', 'Internal Energy - u', 'Enthalpy - h', 'Entropy - s', 'x'};
            app.PropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1DropDownValueChanged, true);
            app.PropertyNo1DropDown.Value = 'Select';
            app.PropertyNo2DropDownLabel = uilabel(app.GridLayout2);
            app.PropertyNo2DropDownLabel.Text = 'Property No.2';
            app.PropertyNo2DropDown = uidropdown(app.GridLayout2);
            app.PropertyNo2DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Sprecifiv Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h', 'x'};
            app.PropertyNo2DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo2DropDownValueChanged, true);
            app.PropertyNo2DropDown.Value = 'Select';
            app.Prop1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Prop2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Phase'; 'P'; 'T'; 'v'; 'u'; 'h'; 'x'};
            app.UITable.RowName = {};
            app.UnitProp1DropDown = uidropdown(app.GridLayout2);
            app.UnitProp1DropDown.Items = {};
            app.UnitProp1DropDown.Value = {};
            app.UnitProp2DropDown = uidropdown(app.GridLayout2);
            app.UnitProp2DropDown.Items = {};
            app.UnitProp2DropDown.Value = {};
            app.StatesPropertiesLabel = uilabel(app.GridLayout2);
            app.StatesPropertiesLabel.Text = ' States Properties';
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UnitLabel = uilabel(app.GridLayout2);
            app.UnitLabel.Text = 'Unit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end